classdef Sorting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HKAppUIFigure                   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SORTINGALGORITHMLabel           matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        HalimezgeKabak180403001Label    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SortingTypesButtonGroup         matlab.ui.container.ButtonGroup
        MergeButton                     matlab.ui.control.RadioButton
        Insertion                       matlab.ui.control.RadioButton
        NothingSelectedButton           matlab.ui.control.RadioButton
        BubbleButton                    matlab.ui.control.RadioButton
        QuickSortButton                 matlab.ui.control.RadioButton
        CheckIfYouWantedRandomPivotCheckBox  matlab.ui.control.CheckBox
        Spinner                         matlab.ui.control.Spinner
        HeapSortButton                  matlab.ui.control.RadioButton
        CountingSortButton              matlab.ui.control.RadioButton
        RadixSortButton                 matlab.ui.control.RadioButton
        BucketSortButton                matlab.ui.control.RadioButton
        ResetAllButton                  matlab.ui.control.Button
        SelectionSortButton             matlab.ui.control.RadioButton
        ShellSortButton                 matlab.ui.control.RadioButton
        Image2                          matlab.ui.control.Image
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        Button_2                        matlab.ui.control.ToggleButton
        Button_3                        matlab.ui.control.ToggleButton
        CLEARButton                     matlab.ui.control.ToggleButton
        SKIPButton                      matlab.ui.control.ToggleButton
        Panel_3                         matlab.ui.container.Panel
        GraphVisibiltySwitchLabel       matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        Panel_6                         matlab.ui.container.Panel
        SliderLabel                     matlab.ui.control.Label
        SpeedSlider                     matlab.ui.control.Slider
        MinKnob                         matlab.ui.control.Knob
        MaxKnob                         matlab.ui.control.Knob
        MinimumVelocityLabel            matlab.ui.control.Label
        MaxVelocityLabel                matlab.ui.control.Label
        MultiplierSpeed                 matlab.ui.control.DiscreteKnob
        MultiplierLabel                 matlab.ui.control.Label
        VelocityLabel                   matlab.ui.control.Label
        EditField_Vel                   matlab.ui.control.NumericEditField
        Panel_5                         matlab.ui.container.Panel
        EnterMaxArrayElementLabel       matlab.ui.control.Label
        MaxValueEditField               matlab.ui.control.NumericEditField
        MultiplierLabel_2               matlab.ui.control.Label
        LengthEditFieldLabel            matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        ArrayLengthKnob                 matlab.ui.control.DiscreteKnob
        EditField                       matlab.ui.control.EditField
        ArrayLengthEditField_2Label     matlab.ui.control.Label
        ArrayLengthEditField_2          matlab.ui.control.EditField
        ArrayLengthKnob_2               matlab.ui.control.DiscreteKnob
        EnterYourArrayWithCommasEditFieldLabel  matlab.ui.control.Label
        EnterYourArrayWithCommasEditField  matlab.ui.control.EditField
        EnterNumberToAddArrayEditFieldLabel  matlab.ui.control.Label
        EnterNumberToAddArrayEditField  matlab.ui.control.EditField
        Knob                            matlab.ui.control.DiscreteKnob
        Knob_2                          matlab.ui.control.DiscreteKnob
        ArrayModsLabel                  matlab.ui.control.Label
        SpecialArraysLabel              matlab.ui.control.Label
        Spinner_2                       matlab.ui.control.Spinner
        CheckForSeedValueCheckBox       matlab.ui.control.CheckBox
        SeedNumberLabel                 matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Panel_7                         matlab.ui.container.Panel
        EnterImageFileEditFieldLabel    matlab.ui.control.Label
        EnterImageFileEditField         matlab.ui.control.EditField
        AddArrayFromImageButton         matlab.ui.control.Button
        SaveArrayAsCsvButton            matlab.ui.control.Button
        InsertArrayFromExcelButton      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        randomArray; % Array itself
        isWorking;   % On/Off
        projectSpeed; %Speed
        arrayLen;     %Array size
        seed;
        spesific;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, log, exit)
            
            app.projectSpeed = 0.01*(200 - app.SpeedSlider.Value);
            app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
           
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
          
            arrayMax = app.MaxValueEditField.Value;
            if arrayMax<=0
                msgbox('Invalid Value', 'Error','error');
            else
             app.randomArray = randi(arrayMax,1,app.arrayLen);
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end
            end
            ax=app.UIAxes; 
            ylim(ax, [0,arrayMax+5]);


            appdesigner("exit.mlapp")
            appdesigner("log.mlapp")
        end

        % Button down function: UIAxes
        function Plot(app, event)
            %value = app.SpeedSlider.Value;
             %plot(app.UIAxes, value*peaks)
            %app.UIAxes.YLim = [-1000 1000];
        end

        % Value changed function: ArrayLengthKnob, 
        % ArrayLengthKnob_2, Knob, Knob_2
        function Generate(app, event)
            ax=app.UIAxes; 
            ylim(ax, [0,app.MaxValueEditField.Value+5]);
          if app.MaxValueEditField.Value<=0
             msgbox('Invalid Value', 'Error','error');
          
       
          else
          if app.CheckForSeedValueCheckBox.Value==1
                rng('default')
                rng(app.seed)  
                 if app.Knob.Value==1
                app.Knob_2.Enable="off";
                app.randomArray=str2double(strsplit(app.EnterYourArrayWithCommasEditField.Value,','));
                app.arrayLen=length(app.randomArray);
                 Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end
             
            drawnow update
            elseif app.Knob.Value==2
                app.Knob_2.Enable="off";
            new=str2double(strsplit(app.EnterNumberToAddArrayEditField.Value));
            app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            app.randomArray(app.arrayLen+1)=new;
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end

            drawnow update
            
            elseif app.Knob.Value==3
            app.Knob_2.Enable="on";
             if app.Knob_2.Value==4
            app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            
            
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            
            
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end
            drawnow update
            
             elseif app.Knob_2.Value==5
                 app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            app.randomArray = randperm(arrayMax,app.arrayLen);
             
            
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end

            drawnow update
            elseif app.Knob_2.Value==6
               app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            a= randi(arrayMax,1,app.arrayLen);
            for i=1:app.arrayLen
             if mod(a(i),2)==1
                 app.randomArray(i)=a(i);
             else
                 app.randomArray(i)=1;
               
             end
            end
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end

            drawnow update
              elseif app.Knob_2.Value==7
               app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            b= randi(arrayMax,1,app.arrayLen);
            for i=1:app.arrayLen
             if mod(b(i),2)==0
                 app.randomArray(i)=b(i);
             else
                 app.randomArray(i)=0;
               
             end
            end
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end

            drawnow update
             end
                 end
          else
           if app.Knob.Value==1
                app.Knob_2.Enable="off";
                app.randomArray=str2double(strsplit(app.EnterYourArrayWithCommasEditField.Value,','));
                app.arrayLen=length(app.randomArray);
                 Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end
             
            drawnow update
            elseif app.Knob.Value==2
                app.Knob_2.Enable="off";
            new=str2double(strsplit(app.EnterNumberToAddArrayEditField.Value));
            app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            app.randomArray(app.arrayLen+1)=new;
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end

            drawnow update
            
            elseif app.Knob.Value==3
            app.Knob_2.Enable="on";
             if app.Knob_2.Value==4
            app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            
            
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            
            
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end
            drawnow update
            
             elseif app.Knob_2.Value==5
                 app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            app.randomArray = randperm(arrayMax,app.arrayLen);
             
            
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end

            drawnow update
            elseif app.Knob_2.Value==6
               app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            a= randi(arrayMax,1,app.arrayLen);
            for i=1:app.arrayLen
             if mod(a(i),2)==1
                 app.randomArray(i)=a(i);
             else
                 app.randomArray(i)=1;
               
             end
            end
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end

            drawnow update
              elseif app.Knob_2.Value==7
               app.LengthEditField.Value=str2double(app.ArrayLengthKnob.Value);
            app.EditField.Value=char(app.ArrayLengthKnob_2.Value);
            x=str2double(app.EditField.Value);
            y=app.LengthEditField.Value;
            app.arrayLen = x*y;
            app.ArrayLengthEditField_2.Value=num2str(app.arrayLen);
            arrayMax = app.MaxValueEditField.Value;
            b= randi(arrayMax,1,app.arrayLen);
            for i=1:app.arrayLen
             if mod(b(i),2)==0
                 app.randomArray(i)=b(i);
             else
                 app.randomArray(i)=0;
               
             end
            end
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end

            drawnow update
             end
            end
          end
         
          end


        end

        % Callback function: ButtonGroup_2, SpeedSlider
        function Speed(app, event)
           
            
            app.SpeedSlider.Limits=[(app.MinKnob.Value*app.MultiplierSpeed.Value),(app.MaxKnob.Value*app.MultiplierSpeed.Value)];
            
            app.projectSpeed = 0.01*(100 - app.SpeedSlider.Value);
            app.EditField_Vel.Value=app.projectSpeed;
             if app.Button_2.Value==1
                 app.isWorking = 1;
             else
                 app.isWorking =0;
                 app.NothingSelectedButton.Value=1;
             end
             if app.CLEARButton.Value==1
                   cla(app.UIAxes);
             
                 return
             end
             if app.SKIPButton.Value==1
                 
                     app.randomArray=sort(app.randomArray);
                      bar(app.UIAxes, app.randomArray)
                 Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                        end
                drawnow update
               
             end
             
       
        end

        % Callback function: CheckIfYouWantedRandomPivotCheckBox, 
        % SortingTypesButtonGroup, Spinner
        function ButtonGroupSelectionChanged(app, event)
         if app.isWorking==1   
                 
            if app.Insertion.Value==1 
                app.BucketSortButton.Enable="off";
                 app.MergeButton.Enable="off";
                 app.BubbleButton.Enable="off";
                 app.QuickSortButton.Enable="off";
                 app.HeapSortButton.Enable="off";
                 app.CountingSortButton.Enable="off";
                 app.RadixSortButton.Enable="off";
                pause('on')
            
                 Y=app.randomArray;
                      bar(app.UIAxes,Y);
                     
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                for j = 2:app.arrayLen
                      Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                        end
                    key_number  =   app.randomArray(j);
                    i           =   j-1           ;
                    b = bar(app.UIAxes,app.randomArray,'FaceColor','flat');
                        b.CData(j,:) = [1 0 0];
                        b.CData(j-1,:) = [1 0 0];
                            
                        
                    while (i>0) && (app.randomArray(i)>key_number) && app.isWorking
                      
                        app.randomArray(i+1)    =   app.randomArray(i)  ;
                        b = bar(app.UIAxes,app.randomArray,'FaceColor','flat');
                        b.CData(i+1,:) = [1 0 0];
                        b.CData(i+2,:) = [1 0 0];
                        i                   =   i-1             ;
                        
                        pause(app.projectSpeed)
                        
                        Y=app.randomArray;
                        bar(app.UIAxes,Y);
                     
                       for k=1:1:length(app.randomArray)
                            text(app.UIAxes, k:k, Y(k)', num2str(Y(k)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                      
                                     
                        drawnow update
                         
                    end
                    app.randomArray(i+1) = key_number;
                    
                     b = bar(app.UIAxes,app.randomArray,'FaceColor','flat');
                     b.CData(i+1,:) = [1 0 0];
                    
                bar(app.UIAxes, app.randomArray)
                 Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                      
                drawnow update
                end
                Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                       beep 
                       icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
            elseif app.NothingSelectedButton==1
                app.randomArray=app.randomArray;
                app.Insertion.Enable="on";
                 app.MergeButton.Enable="on";
                 app.BubbleButton.Enable="on";
                 app.QuickSortButton.Enable="on";
                 app.HeapSortButton.Enable="on";
                 app.CountingSortButton.Enable="on";
                 app.RadixSortButton.Enable="on";
                 app.BucketSortButton.Enable="on";
            
            elseif app.MergeButton.Value==1 
                    if app.isWorking == 1
                        app.Insertion.Enable="off";
                         app.BucketSortButton.Enable="off";
                         app.BubbleButton.Enable="off";
                         app.QuickSortButton.Enable="off";
                         app.HeapSortButton.Enable="off";
                         app.CountingSortButton.Enable="off";
                         app.RadixSortButton.Enable="off";
                        app.randomArray = mergeSort_1(app.randomArray,1,length(app.randomArray),app,event);
                    beep
                     icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
                    end
            elseif app.BubbleButton.Value==1
                pause("on")
                if app.isWorking==1
                 app.Insertion.Enable="off";
                 app.MergeButton.Enable="off";
                 app.BucketSortButton.Enable="off";
                 app.QuickSortButton.Enable="off";
                 app.HeapSortButton.Enable="off";
                 app.CountingSortButton.Enable="off";
                 app.RadixSortButton.Enable="off";
                    n=app.arrayLen;
                    Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                    for i=1:n-1
                        for j=n:-1:i+1
                        if app.randomArray(j)<app.randomArray(j-1) && app.isWorking==1
                            pause(app.projectSpeed)
                            key=app.randomArray(j);
                            app.randomArray(j)=app.randomArray(j-1);
                            app.randomArray(j-1)=key;
                            
                            b = bar(app.UIAxes,app.randomArray,'FaceColor','flat');
                            b.CData(j,:) = [1 0 0];
                            bar(app.UIAxes, app.randomArray)
                            Y=app.randomArray;
                            bar(app.UIAxes,Y);
                                for m=1:1:length(app.randomArray)
                                text(app.UIAxes, m:m, Y(m)', num2str(Y(m)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                                end
                            drawnow update
                        end
                        bar(app.UIAxes, app.randomArray)
                         Y=app.randomArray;
                         bar(app.UIAxes,Y);
                       for y=1:1:length(app.randomArray)
                            text(app.UIAxes, y:y, Y(y)', num2str(Y(y)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                        b = bar(app.UIAxes,app.randomArray,'FaceColor','flat');
                        b.CData(j,:) = [1 0 0];
                        end
                    end
                     bar(app.UIAxes, app.randomArray)
                     Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                     drawnow update
                     beep
                      icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
                end
            elseif app.QuickSortButton.Value==1
                 pause('on')
            if app.isWorking == 1
                app.Insertion.Enable="off";
                 app.MergeButton.Enable="off";
                 app.BubbleButton.Enable="off";
                 app.BucketSortButton.Enable="off";
                 app.HeapSortButton.Enable="off";
                 app.CountingSortButton.Enable="off";
                 app.RadixSortButton.Enable="off";
                if app.CheckIfYouWantedRandomPivotCheckBox.Value==0
                    Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                app.randomArray = quicksort(app.randomArray,1,length(app.randomArray),app,event);
                bar(app.UIAxes, app.randomArray)
                Y=app.randomArray;
                bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                drawnow update
                else
                    Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                    app.Spinner.Visible="on";
                    app.Spinner.Limits=[1,app.arrayLen];
                    p=app.Spinner.Value;
                    app.randomArray=quicksort(app.randomArray,p,length(app.randomArray),app,event);
                    bar(app.UIAxes, app.randomArray)
                    Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
                    drawnow update
                    beep
                     icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
                end
            end
            elseif app.HeapSortButton.Value==1
                if app.isWorking == 1
                 app.Insertion.Enable="off";
                 app.MergeButton.Enable="off";
                 app.BubbleButton.Enable="off";
                 app.QuickSortButton.Enable="off";
                 app.BucketSortButton.Enable="off";
                 app.CountingSortButton.Enable="off";
                 app.RadixSortButton.Enable="off";   
                app.randomArray = heapsort(app.randomArray, app, event);
                beep
                 icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
                end
            elseif app.CountingSortButton.Value==1
                if app.isWorking == 1
                 app.Insertion.Enable="off";
                 app.MergeButton.Enable="off";
                 app.BubbleButton.Enable="off";
                 app.QuickSortButton.Enable="off";
                 app.HeapSortButton.Enable="off";
                 app.BucketSortButton.Enable="off";
                 app.RadixSortButton.Enable="off";
                app.randomArray = countingsorting_app(app, event);
                beep
                 icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
                end
            elseif app.RadixSortButton.Value==1
                if app.isWorking == 1
                    app.Insertion.Enable="off";
                 app.MergeButton.Enable="off";
                 app.BubbleButton.Enable="off";
                 app.QuickSortButton.Enable="off";
                 app.HeapSortButton.Enable="off";
                 app.CountingSortButton.Enable="off";
                 app.BucketSortButton.Enable="off";
                app.randomArray = radixsorting_app(app.randomArray,app, event);
                beep
                 icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
                end
            elseif app.BucketSortButton.Value==1
                if app.isWorking == 1
                 app.Insertion.Enable="off";
                 app.MergeButton.Enable="off";
                 app.BubbleButton.Enable="off";
                 app.QuickSortButton.Enable="off";
                 app.HeapSortButton.Enable="off";
                 app.CountingSortButton.Enable="off";
                 app.RadixSortButton.Enable="off";
                 
                app.randomArray = bucketsorting_app(app.randomArray,app,event);
                end
                elseif app.SelectionSortButton.Value==1
                    if app.isWorking == 1
                        app.Insertion.Enable="off";
                         app.BucketSortButton.Enable="off";
                         app.BubbleButton.Enable="off";
                         app.QuickSortButton.Enable="off";
                         app.HeapSortButton.Enable="off";
                         app.CountingSortButton.Enable="off";
                         app.RadixSortButton.Enable="off";
                         app.MergeButton.Enable="off";
                         app.ShellSortButton.Enable="off";
                        app.randomArray=Selection_Sort1(app,app.randomArray);
                        beep
                         icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
                    end
            elseif app.ShellSortButton.Value==1
                     if app.isWorking == 1
                        app.Insertion.Enable="off";
                         app.BucketSortButton.Enable="off";
                         app.BubbleButton.Enable="off";
                         app.QuickSortButton.Enable="off";
                         app.HeapSortButton.Enable="off";
                         app.CountingSortButton.Enable="off";
                         app.RadixSortButton.Enable="off";
                         app.MergeButton.Enable="off";
                         app.SelectionSortButton.Enable="off";
                        app.randomArray=shell_sort_app(app,app.randomArray);
                        beep
                         icon=imread('Logohok (1).png');
                       msgbox("Sorting Is Completed","HÖK App","custom",icon)
                    end

            end
         else
             app.randomArray=app.randomArray;
         end


        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if value==1
                app.UIAxes.Visible="off";
                set(app.UIAxes,'BackgroundColor','r')
                bar(app.UIAxes,0,0)
                app.Image2.Visible="on";
            else
                app.UIAxes.Visible="on";
                
                Y=app.randomArray;
            bar(app.UIAxes,Y);
                for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                 app.Image2.Visible="off";
                end
            end
            
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
        app.Insertion.Enable="on";
                 app.MergeButton.Enable="on";
                 app.BubbleButton.Enable="on";
                 app.QuickSortButton.Enable="on";
                 app.HeapSortButton.Enable="on";
                 app.CountingSortButton.Enable="on";
                 app.RadixSortButton.Enable="on";
                 app.BucketSortButton.Enable="on";
       
        
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             close(app.HKAppUIFigure)
            log;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
             close(app.HKAppUIFigure)
            exit;
        end

        % Button pushed function: AddArrayFromImageButton
        function AddArrayFromImageButtonPushed(app, event)
            c=app.EnterImageFileEditField.Value;
            businessCard   = imread(c);
           ocrResults     = ocr(businessCard);
           recognizedText = ocrResults.Text;    
            
           figure;
             imshow(businessCard);
             text(600, 150, recognizedText, 'BackgroundColor', [1 1 1]);
             app.EnterYourArrayWithCommasEditField.Value=recognizedText;
        end

        % Button pushed function: SaveArrayAsCsvButton
        function SaveArrayAsCsvButtonPushed(app, event)
             [FileNameBodeWrite, PathNameBodeWrite] = uiputfile({'*.csv'},'Save As...',['defname' '.csv']);
               if FileNameBodeWrite ~=0
                    if exist([PathNameBodeWrite FileNameBodeWrite],'file')
                        delete([PathNameLachWrite FileNameBodeWrite ]);
                    end        
                    xlswrite([PathNameBodeWrite FileNameBodeWrite ],app.randomArray,1,'A2') 
               end
        end

        % Button pushed function: InsertArrayFromExcelButton
        function InsertArrayFromExcelButtonPushed(app, event)
             [file_name,PathName] = uigetfile({'.xls;.xlsx;'},'Please select excel file');figure(app.HKAppUIFigure)
                    FileName=[PathName file_name];
                    imported_array=readmatrix(FileName);
%                           
                    
                app.randomArray=imported_array;
            bar(app.UIAxes,app.randomArray);
            Y=app.randomArray;
                      bar(app.UIAxes,Y);
                       for i=1:1:length(app.randomArray)
                            text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                            
                       end
          
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            app.seed = app.Spinner_2.Value;
            
        end

        % Callback function
        function FindSpesificNumbersListBoxValueChanged(app, event)
            app.specific = app.FindSpesificNumbersListBox.Value;
            
        end

        % Callback function
        function Button_6Pushed(app, event)
             if app.spesific==1
                for i=1:length(app.randomArray)
                    if amod(app.randomArray(i),2)==0
                        disp(app.randomArray(i))
                        app.EditField_2.Value=string(app.randomArray(i));
                    end
                end
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HKAppUIFigure = uifigure('Visible', 'off');
            app.HKAppUIFigure.Name = 'HÖK App';
            app.HKAppUIFigure.Icon = 'Logohok (1).png';
            app.GridLayout = uigridlayout(app.HKAppUIFigure);
            app.SORTINGALGORITHMLabel = uilabel(app.GridLayout);
            app.SORTINGALGORITHMLabel.Text = 'SORTING ALGORITHM';
            app.Panel = uipanel(app.GridLayout);
            app.Panel_2 = uipanel(app.GridLayout);
            app.HalimezgeKabak180403001Label = uilabel(app.Panel_2);
            app.HalimezgeKabak180403001Label.Text = 'Halime Özge Kabak 180403001';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = '1d5ebd5a-7bef-4aa4-ab48-c893eebc041e.png';
            app.SortingTypesButtonGroup = uibuttongroup(app.GridLayout);
            app.SortingTypesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SortingTypesButtonGroup.Title = 'Sorting Types';
            app.MergeButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.MergeButton.Text = 'Merge';
            app.Insertion = uiradiobutton(app.SortingTypesButtonGroup);
            app.Insertion.Text = 'Insertion';
            app.NothingSelectedButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.NothingSelectedButton.Text = 'Nothing Selected ';
            app.NothingSelectedButton.Value = true;
            app.BubbleButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.BubbleButton.Text = 'Bubble';
            app.QuickSortButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.QuickSortButton.Text = 'Quick Sort';
            app.CheckIfYouWantedRandomPivotCheckBox = uicheckbox(app.SortingTypesButtonGroup);
            app.CheckIfYouWantedRandomPivotCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.CheckIfYouWantedRandomPivotCheckBox.Text = {'Check If You '; 'Wanted Random Pivot '};
            app.Spinner = uispinner(app.SortingTypesButtonGroup);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Spinner.Visible = 'off';
            app.HeapSortButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.HeapSortButton.Text = 'Heap Sort';
            app.CountingSortButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.CountingSortButton.Text = 'Counting Sort';
            app.RadixSortButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.RadixSortButton.Text = 'Radix Sort';
            app.BucketSortButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.BucketSortButton.Text = 'Bucket Sort';
            app.ResetAllButton = uibutton(app.SortingTypesButtonGroup, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = {'Reset'; 'All'};
            app.SelectionSortButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.SelectionSortButton.Text = 'Selection Sort';
            app.ShellSortButton = uiradiobutton(app.SortingTypesButtonGroup);
            app.ShellSortButton.Text = 'Shell Sort';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = '6246485.jpg';
            app.ButtonGroup_2 = uibuttongroup(app.GridLayout);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @Speed, true);
            app.Button_2 = uitogglebutton(app.ButtonGroup_2);
            app.Button_2.Icon = '110-1109345_start-button-for-game-graphics (2).png';
            app.Button_2.Text = '';
            app.Button_3 = uitogglebutton(app.ButtonGroup_2);
            app.Button_3.Icon = 'unnamed (1).png';
            app.Button_3.Text = '';
            app.Button_3.Value = true;
            app.CLEARButton = uitogglebutton(app.ButtonGroup_2);
            app.CLEARButton.Icon = 'Clear-icon.png';
            app.CLEARButton.Text = 'CLEAR';
            app.SKIPButton = uitogglebutton(app.ButtonGroup_2);
            app.SKIPButton.Icon = 'C:\Users\Özge\Desktop\Halime Özge Kabak Matlab\final1\images\transparent-skip-icon-next-icon-essential-set-icon-5d7850b91fe622.2853653915681660731307.jpg';
            app.SKIPButton.Text = 'SKIP';
            app.Panel_3 = uipanel(app.GridLayout);
            app.GraphVisibiltySwitchLabel = uilabel(app.Panel_3);
            app.GraphVisibiltySwitchLabel.Text = 'Graph Visibilty Switch:';
            app.Switch_2 = uiswitch(app.Panel_3, 'slider');
            app.Switch_2.Items = {'Visible', 'Invisible'};
            app.Switch_2.ItemsData = [0 1];
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 0;
            app.Panel_6 = uipanel(app.GridLayout);
            app.Panel_6.Title = 'Panel';
            app.SliderLabel = uilabel(app.Panel_6);
            app.SliderLabel.Text = 'Slider';
            app.SpeedSlider = uislider(app.Panel_6);
            app.SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @Speed, true);
            app.MinKnob = uiknob(app.Panel_6, 'continuous');
            app.MinKnob.Limits = [0 10];
            app.MinKnob.Value = 5;
            app.MaxKnob = uiknob(app.Panel_6, 'continuous');
            app.MaxKnob.Limits = [0 20];
            app.MaxKnob.Value = 10;
            app.MinimumVelocityLabel = uilabel(app.Panel_6);
            app.MinimumVelocityLabel.Text = 'Minimum Velocity';
            app.MaxVelocityLabel = uilabel(app.Panel_6);
            app.MaxVelocityLabel.Text = 'Max Velocity';
            app.MultiplierSpeed = uiknob(app.Panel_6, 'discrete');
            app.MultiplierSpeed.Items = {'x1', 'x2', 'x5', 'x10'};
            app.MultiplierSpeed.ItemsData = [1 2 5 10];
            app.MultiplierSpeed.Value = 1;
            app.MultiplierLabel = uilabel(app.Panel_6);
            app.MultiplierLabel.Text = 'Multiplier';
            app.VelocityLabel = uilabel(app.Panel_6);
            app.VelocityLabel.Text = 'Velocity:';
            app.EditField_Vel = uieditfield(app.Panel_6, 'numeric');
            app.Panel_5 = uipanel(app.GridLayout);
            app.EnterMaxArrayElementLabel = uilabel(app.Panel_5);
            app.EnterMaxArrayElementLabel.Text = 'Enter Max Array Element:';
            app.MaxValueEditField = uieditfield(app.Panel_5, 'numeric');
            app.MaxValueEditField.Value = 50;
            app.MultiplierLabel_2 = uilabel(app.Panel_5);
            app.MultiplierLabel_2.Text = 'Multiplier';
            app.LengthEditFieldLabel = uilabel(app.Panel_5);
            app.LengthEditFieldLabel.Text = 'Length:';
            app.LengthEditField = uieditfield(app.Panel_5, 'numeric');
            app.ArrayLengthKnob = uiknob(app.Panel_5, 'discrete');
            app.ArrayLengthKnob.Items = {'1', '', '', '', '5', '', '', '', '', '10', '', '', '', '', '15', '', '', '', '', '20', '', '', '', '', '25', '', '', '', '', '30', '', '', '', '', '35', '', '', '', '', '40', '', '', '', '', '45', '', '', '', '', '50'};
            app.ArrayLengthKnob.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.ArrayLengthKnob.ValueChangedFcn = createCallbackFcn(app, @Generate, true);
            app.ArrayLengthKnob.Value = '1';
            app.EditField = uieditfield(app.Panel_5, 'text');
            app.ArrayLengthEditField_2Label = uilabel(app.Panel_5);
            app.ArrayLengthEditField_2Label.Text = 'Array Length:';
            app.ArrayLengthEditField_2 = uieditfield(app.Panel_5, 'text');
            app.ArrayLengthKnob_2 = uiknob(app.Panel_5, 'discrete');
            app.ArrayLengthKnob_2.Items = {'x1', 'x2', 'x3', 'x4', 'x5', 'x6', 'x7', 'x8', 'x9', 'x10', '', '', '35', '', '', '', '', '40', '', '', '', '', '45', '', '', '', '', '50'};
            app.ArrayLengthKnob_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', ''};
            app.ArrayLengthKnob_2.ValueChangedFcn = createCallbackFcn(app, @Generate, true);
            app.ArrayLengthKnob_2.Value = '1';
            app.EnterYourArrayWithCommasEditFieldLabel = uilabel(app.Panel_5);
            app.EnterYourArrayWithCommasEditFieldLabel.Text = {'Enter Your Array'; 'With Commas'};
            app.EnterYourArrayWithCommasEditField = uieditfield(app.Panel_5, 'text');
            app.EnterNumberToAddArrayEditFieldLabel = uilabel(app.Panel_5);
            app.EnterNumberToAddArrayEditFieldLabel.Text = {'Enter Number'; 'To Add Array'};
            app.EnterNumberToAddArrayEditField = uieditfield(app.Panel_5, 'text');
            app.Knob = uiknob(app.Panel_5, 'discrete');
            app.Knob.Items = {'Add Your Array', 'Add Number To Array', 'Random Array', '', '', '', ''};
            app.Knob.ItemsData = [1 2 3];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @Generate, true);
            app.Knob.Value = 3;
            app.Knob_2 = uiknob(app.Panel_5, 'discrete');
            app.Knob_2.Items = {'Off', 'Non Repeated', 'Only Odd', 'Only Even'};
            app.Knob_2.ItemsData = [4 5 6 7];
            app.Knob_2.ValueChangedFcn = createCallbackFcn(app, @Generate, true);
            app.Knob_2.Value = 4;
            app.ArrayModsLabel = uilabel(app.Panel_5);
            app.ArrayModsLabel.Text = 'Array Mods:';
            app.SpecialArraysLabel = uilabel(app.Panel_5);
            app.SpecialArraysLabel.Text = 'Special Arrays:';
            app.Spinner_2 = uispinner(app.Panel_5);
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.CheckForSeedValueCheckBox = uicheckbox(app.Panel_5);
            app.CheckForSeedValueCheckBox.Text = 'Check For Seed Value';
            app.SeedNumberLabel = uilabel(app.Panel_5);
            app.SeedNumberLabel.Text = 'Seed Number:';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'indir.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = '95841021-emergency-exit-simple-icon.jpg';
            app.Button_5.Text = '';
            app.Panel_7 = uipanel(app.GridLayout);
            app.EnterImageFileEditFieldLabel = uilabel(app.Panel_7);
            app.EnterImageFileEditFieldLabel.Text = 'Enter Image File:';
            app.EnterImageFileEditField = uieditfield(app.Panel_7, 'text');
            app.AddArrayFromImageButton = uibutton(app.Panel_7, 'push');
            app.AddArrayFromImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddArrayFromImageButtonPushed, true);
            app.AddArrayFromImageButton.Text = 'Add Array From Image';
            app.SaveArrayAsCsvButton = uibutton(app.Panel_7, 'push');
            app.SaveArrayAsCsvButton.ButtonPushedFcn = createCallbackFcn(app, @SaveArrayAsCsvButtonPushed, true);
            app.SaveArrayAsCsvButton.Icon = 'images (2).png';
            app.SaveArrayAsCsvButton.Text = 'Save Array As Csv';
            app.InsertArrayFromExcelButton = uibutton(app.Panel_7, 'push');
            app.InsertArrayFromExcelButton.ButtonPushedFcn = createCallbackFcn(app, @InsertArrayFromExcelButtonPushed, true);
            app.InsertArrayFromExcelButton.Icon = 'png-clipart-macos-app-icons-microsoft-excel.png';
            app.InsertArrayFromExcelButton.Text = 'Insert Array From Excel';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, {'Sorting'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 10];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @Plot, true);
            % Show the figure after all components are created
            app.HKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sorting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HKAppUIFigure)
        end
    end
end