classdef binsearch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HKAppUIFigure                 matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        BinarySearchLabel             matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        GenerateAnArrayButton         matlab.ui.control.Button
        SortTheArrayButton            matlab.ui.control.Button
        ChooseANumberFromArrayLabel   matlab.ui.control.Label
        ChooseANumberFromArrayEditField  matlab.ui.control.NumericEditField
        EnterArrayLengthLabel         matlab.ui.control.Label
        EnterArrayLengthEditField     matlab.ui.control.NumericEditField
        EnterMaxValueOfElementsLabel  matlab.ui.control.Label
        EnterMaxValueOfElementsEditField  matlab.ui.control.NumericEditField
        SearchForChoosenNumberButton  matlab.ui.control.Button
        IndexOfTheChoosenNumberEditFieldLabel  matlab.ui.control.Label
        IndexOfTheChoosenNumberEditField  matlab.ui.control.NumericEditField
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        randomArray; 
        arrayLen;    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arrayLen=app.EnterArrayLengthEditField.Value;
            arrayMax=app.EnterMaxValueOfElementsEditField.Value;
            app.randomArray = randi(arrayMax,1,app.arrayLen);
             ax=app.UIAxes; 
            ylim(ax, [0,arrayMax+5]);
            appdesigner("log.mlapp")
            appdesigner("exit.mlapp")
        end

        % Button pushed function: GenerateAnArrayButton
        function GenerateAnArrayButtonPushed(app, event)
             ax=app.UIAxes; 
            ylim(ax, [0,app.EnterMaxValueOfElementsEditField.Value+5]);
            app.arrayLen=app.EnterArrayLengthEditField.Value;
            arrayMax=app.EnterMaxValueOfElementsEditField.Value;
            if app.arrayLen<=0 || arrayMax<=0
                 msgbox('Invalid Value', 'Error','error');
            else
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            bar(app.UIAxes, app.randomArray)
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end
            end
        end

        % Button pushed function: SortTheArrayButton
        function SortTheArrayButtonPushed(app, event)
            app.randomArray=Mergesort_mid(app.randomArray,app,event);
            bar(app.UIAxes,app.randomArray);
            
            Y=app.randomArray;
            for i=1:1:length(Y)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                                    
            end
            app.SearchForChoosenNumberButton.Enable="on";
        end

        % Button pushed function: SearchForChoosenNumberButton
        function SearchForChoosenNumberButtonPushed(app, event)
            y=app.ChooseANumberFromArrayEditField.Value;
            x=linear_search(app.randomArray,y);
            if x==0
             msgbox('Selected number not found in array', 'Error','error');   
            else
            num=binarysearch_app1(app.randomArray,y,app,event);
            app.IndexOfTheChoosenNumberEditField.Value=num;
            b=bar(app.UIAxes,app.randomArray,'FaceColor','flat','EdgeColor',[0 .9 .9]);
            b.CData(num,:)=[1 0 0];
            
             Y=app.randomArray;
            for i=1:1:length(Y)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                                    
            end
            
              
            end
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            close(app.HKAppUIFigure)
            log;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            close(app.HKAppUIFigure)
            exit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HKAppUIFigure = uifigure('Visible', 'off');
            app.HKAppUIFigure.Name = 'HÖK App';
            app.HKAppUIFigure.Icon = 'Logohok (1).png';
            app.GridLayout = uigridlayout(app.HKAppUIFigure);
            app.BinarySearchLabel = uilabel(app.GridLayout);
            app.BinarySearchLabel.Text = 'Binary Search';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Logohok (1).png';
            app.GenerateAnArrayButton = uibutton(app.GridLayout, 'push');
            app.GenerateAnArrayButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAnArrayButtonPushed, true);
            app.GenerateAnArrayButton.Text = {'Generate '; 'An Array'};
            app.SortTheArrayButton = uibutton(app.GridLayout, 'push');
            app.SortTheArrayButton.ButtonPushedFcn = createCallbackFcn(app, @SortTheArrayButtonPushed, true);
            app.SortTheArrayButton.Text = 'Sort The Array';
            app.ChooseANumberFromArrayLabel = uilabel(app.GridLayout);
            app.ChooseANumberFromArrayLabel.Text = {'Choose A '; 'Number From'; 'Array:'};
            app.ChooseANumberFromArrayEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChooseANumberFromArrayEditField.Value = 5;
            app.EnterArrayLengthLabel = uilabel(app.GridLayout);
            app.EnterArrayLengthLabel.Text = {'Enter Array '; 'Length:'};
            app.EnterArrayLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.EnterArrayLengthEditField.Value = 10;
            app.EnterMaxValueOfElementsLabel = uilabel(app.GridLayout);
            app.EnterMaxValueOfElementsLabel.Text = {'Enter Max Value'; ' Of Elements:'};
            app.EnterMaxValueOfElementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.EnterMaxValueOfElementsEditField.Value = 50;
            app.SearchForChoosenNumberButton = uibutton(app.GridLayout, 'push');
            app.SearchForChoosenNumberButton.ButtonPushedFcn = createCallbackFcn(app, @SearchForChoosenNumberButtonPushed, true);
            app.SearchForChoosenNumberButton.Enable = 'off';
            app.SearchForChoosenNumberButton.Text = {'Search For'; 'Choosen Number'};
            app.IndexOfTheChoosenNumberEditFieldLabel = uilabel(app.GridLayout);
            app.IndexOfTheChoosenNumberEditFieldLabel.Text = {'Index Of The'; 'Choosen Number:'};
            app.IndexOfTheChoosenNumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '95841021-emergency-exit-simple-icon.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'indir.png';
            app.Button_2.Text = '';
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'Index')
            ylabel(app.UIAxes, 'Number')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.HKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = binsearch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HKAppUIFigure)
        end
    end
end