classdef comparison < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HKAppUIFigure                 matlab.ui.Figure
        InsertionSortCheckBox         matlab.ui.control.CheckBox
        MergeSortCheckBox             matlab.ui.control.CheckBox
        BubbleSortCheckBox            matlab.ui.control.CheckBox
        QuickSortCheckBox             matlab.ui.control.CheckBox
        HeapSortCheckBox              matlab.ui.control.CheckBox
        CountingSortCheckBox          matlab.ui.control.CheckBox
        RadixSortCheckBox             matlab.ui.control.CheckBox
        BucketSortCheckBox            matlab.ui.control.CheckBox
        Button                        matlab.ui.control.Button
        Switch                        matlab.ui.control.Switch
        Button_2                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        CompareAllSortingTypesButton  matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
        InsertionEditFieldLabel       matlab.ui.control.Label
        InsertionEditField            matlab.ui.control.EditField
        MergeEditFieldLabel           matlab.ui.control.Label
        MergeEditField                matlab.ui.control.EditField
        BubbleEditFieldLabel          matlab.ui.control.Label
        BubbleEditField               matlab.ui.control.EditField
        QuickEditFieldLabel           matlab.ui.control.Label
        QuickEditField                matlab.ui.control.EditField
        HeapEditFieldLabel            matlab.ui.control.Label
        HeapEditField                 matlab.ui.control.EditField
        CountingEditFieldLabel        matlab.ui.control.Label
        CountingEditField             matlab.ui.control.EditField
        RadixEditFieldLabel           matlab.ui.control.Label
        RadixEditField                matlab.ui.control.EditField
        BucketEditFieldLabel          matlab.ui.control.Label
        BucketEditField               matlab.ui.control.EditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appdesigner("exit.mlapp");
            appdesigner("log.mlapp");
        end

        % Callback function: BubbleSortCheckBox, BucketSortCheckBox, 
        % Button, CountingSortCheckBox, InsertionSortCheckBox, 
        % MergeSortCheckBox, QuickSortCheckBox, RadixSortCheckBox, 
        % Switch
        function ButtonPushed(app, event)
        if app.Switch.Value==1
            cla(app.UIAxes)
        if app.InsertionSortCheckBox.Value==1
                e=[];
                t=[];
                [e,t]=comp_insertion(e,t,app,event);
                hold(app.UIAxes,'on')
           plot(app.UIAxes,e,t,'Color','b')
           hold(app.UIAxes,'off')
        end
        if app.CountingSortCheckBox.Value==1
            ce=[];
            ct=[];
            [ce,ct]=comp_counting(ce,ct,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,ce,ct,'Color','r')
            hold(app.UIAxes,'off')
        
        end
        if app.BubbleSortCheckBox.Value==1
            be=[];
            bt=[];
            [be,bt]=comp_bubble(be,bt,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,be,bt,'Color','g')
            hold(app.UIAxes,'off')
        
        end
        if app.MergeSortCheckBox.Value==1
            me=[];
            mt=[];
            [me,mt]=comp_merge(me,mt,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,me,mt,'Color','c')
            hold(app.UIAxes,'off')
        end
        if app.QuickSortCheckBox.Value==1
            qe=[];
            qt=[];
             [qe,qt]=comp_quicksort(qe,qt,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,qe,qt,'Color','m')
            hold(app.UIAxes,'off')
        end
        if app.HeapSortCheckBox.Value==1
            he=[];
            ht=[];
             [he,ht]=comp_heap(he,ht,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,he,ht,'Color','[0.4940 0.1840 0.5560]')
            hold(app.UIAxes,'off')
        end
        if app.RadixSortCheckBox.Value==1
            se=[];
            st=[];
             [se,st]=comp_radix(se,st,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,se,st,'Color','[0.4660 0.6740 0.1880]')
            hold(app.UIAxes,'off')
        end
        if app.BucketSortCheckBox.Value==1
            bue=[];
            but=[];
             [bue,but]=comp_bucket(bue,but,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,bue,but,'Color','[0.9290 0.6940 0.1250]')
            hold(app.UIAxes,'off')
        end
        else
            cla(app.UIAxes)
       end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            close(app.HKAppUIFigure)
            log;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            close(app.HKAppUIFigure)
            exit;
           
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: CompareAllSortingTypesButton
        function CompareAllSortingTypesButtonPushed(app, event)
            cla(app.UIAxes)
            e=[];
            t=[];
            [e,t]=comp_insertion(e,t,app,event);
           hold(app.UIAxes,'on')
           plot(app.UIAxes,e,t,'Color','b')
           hold(app.UIAxes,'off')
            ce=[];
            ct=[];
            [ce,ct]=comp_counting(ce,ct,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,ce,ct,'Color','r')
            hold(app.UIAxes,'off')
             be=[];
            bt=[];
            [be,bt]=comp_bubble(be,bt,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,be,bt,'Color','g')
            hold(app.UIAxes,'off')
            me=[];
            mt=[];
            [me,mt]=comp_merge(me,mt,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,me,mt,'Color','c')
            hold(app.UIAxes,'off')
             qe=[];
            qt=[];
             [qe,qt]=comp_quicksort(qe,qt,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,qe,qt,'Color','m')
            hold(app.UIAxes,'off')
              he=[];
            ht=[];
             [he,ht]=comp_heap(he,ht,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,he,ht,'Color','[0.4940 0.1840 0.5560]')
            hold(app.UIAxes,'off')
             se=[];
            st=[];
             [se,st]=comp_radix(se,st,app,event);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,se,st,'Color','[0.4660 0.6740 0.1880]')
            hold(app.UIAxes,'off')
              bue=[];
            but=[];
             [bue,but]=comp_bucket(bue,but,app,event);
             
            hold(app.UIAxes,'on')
            plot(app.UIAxes,bue,but,'Color','[0.9290 0.6940 0.1250]')
            hold(app.UIAxes,'off')
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HKAppUIFigure = uifigure('Visible', 'off');
            app.HKAppUIFigure.Name = 'HÖK App';
            app.HKAppUIFigure.Icon = 'Logohok (1).png';
            app.InsertionSortCheckBox = uicheckbox(app.HKAppUIFigure);
            app.InsertionSortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.InsertionSortCheckBox.Text = 'Insertion Sort';
            app.MergeSortCheckBox = uicheckbox(app.HKAppUIFigure);
            app.MergeSortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.MergeSortCheckBox.Text = 'Merge Sort';
            app.BubbleSortCheckBox = uicheckbox(app.HKAppUIFigure);
            app.BubbleSortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.BubbleSortCheckBox.Text = 'Bubble Sort';
            app.QuickSortCheckBox = uicheckbox(app.HKAppUIFigure);
            app.QuickSortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.QuickSortCheckBox.Text = 'Quick Sort';
            app.HeapSortCheckBox = uicheckbox(app.HKAppUIFigure);
            app.HeapSortCheckBox.Text = 'Heap Sort';
            app.CountingSortCheckBox = uicheckbox(app.HKAppUIFigure);
            app.CountingSortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CountingSortCheckBox.Text = 'Counting Sort';
            app.RadixSortCheckBox = uicheckbox(app.HKAppUIFigure);
            app.RadixSortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.RadixSortCheckBox.Text = 'Radix Sort';
            app.BucketSortCheckBox = uicheckbox(app.HKAppUIFigure);
            app.BucketSortCheckBox.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.BucketSortCheckBox.Text = 'Bucket Sort';
            app.Button = uibutton(app.HKAppUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Visible = 'off';
            app.Switch = uiswitch(app.HKAppUIFigure, 'slider');
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Switch.Value = 0;
            app.Button_2 = uibutton(app.HKAppUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'indir.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.HKAppUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = '95841021-emergency-exit-simple-icon.jpg';
            app.Button_3.Text = '';
            app.CompareAllSortingTypesButton = uibutton(app.HKAppUIFigure, 'push');
            app.CompareAllSortingTypesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareAllSortingTypesButtonPushed, true);
            app.CompareAllSortingTypesButton.Text = 'Compare All Sorting Types';
            app.ClearButton = uibutton(app.HKAppUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.InsertionEditFieldLabel = uilabel(app.HKAppUIFigure);
            app.InsertionEditFieldLabel.Text = 'Insertion';
            app.InsertionEditField = uieditfield(app.HKAppUIFigure, 'text');
            app.MergeEditFieldLabel = uilabel(app.HKAppUIFigure);
            app.MergeEditFieldLabel.Text = 'Merge';
            app.MergeEditField = uieditfield(app.HKAppUIFigure, 'text');
            app.BubbleEditFieldLabel = uilabel(app.HKAppUIFigure);
            app.BubbleEditFieldLabel.Text = 'Bubble';
            app.BubbleEditField = uieditfield(app.HKAppUIFigure, 'text');
            app.QuickEditFieldLabel = uilabel(app.HKAppUIFigure);
            app.QuickEditFieldLabel.Text = 'Quick';
            app.QuickEditField = uieditfield(app.HKAppUIFigure, 'text');
            app.HeapEditFieldLabel = uilabel(app.HKAppUIFigure);
            app.HeapEditFieldLabel.Text = 'Heap';
            app.HeapEditField = uieditfield(app.HKAppUIFigure, 'text');
            app.CountingEditFieldLabel = uilabel(app.HKAppUIFigure);
            app.CountingEditFieldLabel.Text = 'Counting';
            app.CountingEditField = uieditfield(app.HKAppUIFigure, 'text');
            app.RadixEditFieldLabel = uilabel(app.HKAppUIFigure);
            app.RadixEditFieldLabel.Text = 'Radix';
            app.RadixEditField = uieditfield(app.HKAppUIFigure, 'text');
            app.BucketEditFieldLabel = uilabel(app.HKAppUIFigure);
            app.BucketEditFieldLabel.Text = 'Bucket';
            app.BucketEditField = uieditfield(app.HKAppUIFigure, 'text');
            app.UIAxes = uiaxes(app.HKAppUIFigure);
            title(app.UIAxes, 'Asymptotic Complexity')
            xlabel(app.UIAxes, 'length')
            ylabel(app.UIAxes, 'time')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.HKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = comparison

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HKAppUIFigure)
        end
    end
end