classdef fibonacci < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HKAppUIFigure                   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.NumericEditField
        CALCULATEButton                 matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        EnternthFibonacciNumberSpinnerLabel  matlab.ui.control.Label
        EnternthFibonacciNumberSpinner  matlab.ui.control.Spinner
        NthFibonacciNumberValueLabel    matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        GoldenRatioGraphButton          matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         % Description
         arrayIndex
         
         array
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arrayIndex=0;
            appdesigner("exit.mlapp")
            appdesigner("log.mlapp")
            appdesigner("graph.mlapp")
        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
             
            app.arrayIndex=app.EnternthFibonacciNumberSpinner.Value;
            app.array=Fibonacci2(app.arrayIndex,app,event);
            b=bar(app.UIAxes,app.array,'FaceColor','flat','EdgeColor',[0.94 0 0.58],'LineWidth',1.5);
            Y=app.array;
            for i=1:1:length(app.array)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                                    
            end
            
            b.CData(app.arrayIndex,:) = [0.97 0.38 0.87];
            m=app.arrayIndex;
            app.EditField.Value=fib1(m);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            close(app.HKAppUIFigure)
            exit;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            close(app.HKAppUIFigure)
            log;
        end

        % Button pushed function: GoldenRatioGraphButton
        function GoldenRatioGraphButtonPushed(app, event)
            graph;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HKAppUIFigure = uifigure('Visible', 'off');
            app.HKAppUIFigure.Name = 'HÖK App';
            app.HKAppUIFigure.Icon = 'Logohok (1).png';
            app.GridLayout = uigridlayout(app.HKAppUIFigure);
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.CALCULATEButton = uibutton(app.GridLayout, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Logohok (1).png';
            app.EnternthFibonacciNumberSpinnerLabel = uilabel(app.GridLayout);
            app.EnternthFibonacciNumberSpinnerLabel.Text = 'Enter nth Fibonacci Number :';
            app.EnternthFibonacciNumberSpinner = uispinner(app.GridLayout);
            app.EnternthFibonacciNumberSpinner.Limits = [0 1000];
            app.EnternthFibonacciNumberSpinner.Value = 1;
            app.NthFibonacciNumberValueLabel = uilabel(app.GridLayout);
            app.NthFibonacciNumberValueLabel.Text = 'Nth Fibonacci Number Value:';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '95841021-emergency-exit-simple-icon.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'indir.png';
            app.Button_2.Text = '';
            app.GoldenRatioGraphButton = uibutton(app.GridLayout, 'push');
            app.GoldenRatioGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GoldenRatioGraphButtonPushed, true);
            app.GoldenRatioGraphButton.Text = 'Golden Ratio Graph';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Fibonacci Sequence')
            xlabel(app.UIAxes, 'Index')
            ylabel(app.UIAxes, 'Values')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.HKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fibonacci

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HKAppUIFigure)
        end
    end
end