classdef ithsmallest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HKAppUIFigure                   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GenerateArrayButton             matlab.ui.control.Button
        EnterArrayLengthEditFieldLabel  matlab.ui.control.Label
        EnterArrayLengthEditField       matlab.ui.control.NumericEditField
        EnterMaxNumberValueLabel        matlab.ui.control.Label
        EnterMaxNumberValueEditField    matlab.ui.control.NumericEditField
        SelectIthSmallestNumberLabel    matlab.ui.control.Label
        SelectIthSmallestNumberEditField  matlab.ui.control.NumericEditField
        IthSmallestNumberValueLabel     matlab.ui.control.Label
        IthSmallestNumberValueEditField  matlab.ui.control.NumericEditField
        FindButton                      matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SortArrayButton                 matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        randomArray; 
        arrayLen;    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.arrayLen=app.EnterArrayLengthEditField.Value;
            arrayMax=app.EnterMaxNumberValueEditField.Value;
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            ax=app.UIAxes; 
            ylim(ax, [0,arrayMax+5]);
            appdesigner("log.mlapp")
            appdesigner("exit.mlapp")
        end

        % Button pushed function: GenerateArrayButton
        function GenerateArrayButtonPushed(app, event)
            
            app.arrayLen=app.EnterArrayLengthEditField.Value;
            arrayMax=app.EnterMaxNumberValueEditField.Value;
             ax=app.UIAxes; 
            ylim(ax, [0,arrayMax+5]);
         if app.arrayLen<=0 || arrayMax<=0
             msgbox('Invalid Value', 'Error','error');
            
        else
            app.randomArray = randi(arrayMax,1,app.arrayLen);
            bar(app.UIAxes, app.randomArray)
            Y=app.randomArray;
            bar(app.UIAxes,Y);
            for i=1:1:length(app.randomArray)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                
            end
         end
        end

        % Button pushed function: SortArrayButton
        function SortArrayButtonPushed(app, event)
             app.randomArray=Mergesort_mid(app.randomArray,app,event);
            bar(app.UIAxes,app.randomArray);
            
            Y=app.randomArray;
            for i=1:1:length(Y)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                                    
            end
            app.FindButton.Enable="on";
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            
            y=app.SelectIthSmallestNumberEditField.Value;
           
            if y<=0 || y>length(app.randomArray)
                msgbox('Invalid Value', 'Error','error');
            else
            num=rand_select(app.randomArray,y,app,event);
            app.IthSmallestNumberValueEditField.Value=num;
            b=bar(app.UIAxes,app.randomArray,'FaceColor','flat','EdgeColor',[0 .9 .9]);
            b.CData(y,:)=[0.49 0.58 0.18];
            
             Y=app.randomArray;
            for i=1:1:length(Y)
                text(app.UIAxes, i:i, Y(i)', num2str(Y(i)','%d'),'HorizontalAlignment','center','VerticalAlignment','bottom')
                                    
            end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            close(app.HKAppUIFigure)
            exit;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            close(app.HKAppUIFigure)
            log;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HKAppUIFigure = uifigure('Visible', 'off');
            app.HKAppUIFigure.Name = 'HÖK App';
            app.HKAppUIFigure.Icon = 'Logohok (1).png';
            app.GridLayout = uigridlayout(app.HKAppUIFigure);
            app.GenerateArrayButton = uibutton(app.GridLayout, 'push');
            app.GenerateArrayButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateArrayButtonPushed, true);
            app.GenerateArrayButton.Text = 'Generate Array';
            app.EnterArrayLengthEditFieldLabel = uilabel(app.GridLayout);
            app.EnterArrayLengthEditFieldLabel.Text = 'Enter Array Length:';
            app.EnterArrayLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.EnterArrayLengthEditField.Value = 10;
            app.EnterMaxNumberValueLabel = uilabel(app.GridLayout);
            app.EnterMaxNumberValueLabel.Text = {'Enter Max '; 'Number Value:'};
            app.EnterMaxNumberValueEditField = uieditfield(app.GridLayout, 'numeric');
            app.EnterMaxNumberValueEditField.Value = 50;
            app.SelectIthSmallestNumberLabel = uilabel(app.GridLayout);
            app.SelectIthSmallestNumberLabel.Text = {'Select Ith '; 'Smallest Number:'};
            app.SelectIthSmallestNumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.IthSmallestNumberValueLabel = uilabel(app.GridLayout);
            app.IthSmallestNumberValueLabel.Text = {'Ith Smallest '; 'Number Value:'};
            app.IthSmallestNumberValueEditField = uieditfield(app.GridLayout, 'numeric');
            app.FindButton = uibutton(app.GridLayout, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Enable = 'off';
            app.FindButton.Text = 'Find';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Logohok (1).png';
            app.SortArrayButton = uibutton(app.GridLayout, 'push');
            app.SortArrayButton.ButtonPushedFcn = createCallbackFcn(app, @SortArrayButtonPushed, true);
            app.SortArrayButton.Text = 'Sort Array';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '95841021-emergency-exit-simple-icon.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'indir.png';
            app.Button_2.Text = '';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Random Select Ith Smallest Number')
            xlabel(app.UIAxes, 'Index')
            ylabel(app.UIAxes, 'Value')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.HKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ithsmallest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HKAppUIFigure)
        end
    end
end