classdef log < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HKAppUIFigure                 matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LogInPanel                    matlab.ui.container.Panel
        EnterButton                   matlab.ui.control.Button
        UserNameEditField             matlab.ui.control.EditField
        UserNameEditFieldLabel        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        Label_2                       matlab.ui.control.Label
        PasswordEditFieldLabel        matlab.ui.control.Label
        PasswordEditField             matlab.ui.control.EditField
        Image                         matlab.ui.control.Image
        Button                        matlab.ui.control.Button
        HalimezgeKABAK180403001Label  matlab.ui.control.Label
        IKCUEEEYoutubePageButton      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        BINARYSEARCHButton            matlab.ui.control.Button
        FIBONACCISEQUENCEButton       matlab.ui.control.Button
        SORTINGButton                 matlab.ui.control.Button
        COMPARISONButton              matlab.ui.control.Button
        MATRIXCALCULATIONSButton      matlab.ui.control.Button
        FINDIthSMALLESTNUMBERButton   matlab.ui.control.Button
        GuideButton                   matlab.ui.control.Button
        EXITButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %appdesigner("app2");
            appdesigner("ithsmallest.mlapp");
            appdesigner("binsearch.mlapp");
            appdesigner("Sorting.mlapp");
            appdesigner("fibonacci.mlapp");
            appdesigner("matrixm.mlapp");
            appdesigner("comparison.mlapp");
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
           name=app.UserNameEditField.Value;
          
           password=app.PasswordEditField.Value;
          
          
           if (name=="Ozge" && password=="12345")
               app.Lamp.Color="g";
               app.Label_2.Text="Entry is succesfull";
               app.LogInPanel.Visible="off";
               %app2;
               app.BINARYSEARCHButton.Visible="on";
               app.COMPARISONButton.Visible="on";
               app.SORTINGButton.Visible="on";
               app.FIBONACCISEQUENCEButton.Visible="on";
               app.FINDIthSMALLESTNUMBERButton.Visible="on";
               app.MATRIXCALCULATIONSButton.Visible="on";
           else
               app.Lamp.Color="r";
               app.Label_2.Text="User name or password is wrong!";
                msgbox('User name or password is wrong!', 'Error','error');
           end
       
          
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web("https://muh.ikcu.edu.tr/","-browser")
        end

        % Button pushed function: IKCUEEEYoutubePageButton
        function IKCUEEEYoutubePageButtonPushed(app, event)
             web("https://www.youtube.com/channel/UCVx9odXaosySGaDtXX96QGA","-browser")
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
           web mailto:ozge13kbk@hotmail.com
         
        end

        % Button pushed function: FINDIthSMALLESTNUMBERButton
        function FINDIthSMALLESTNUMBERButtonPushed(app, event)
            ithsmallest;
        end

        % Button pushed function: BINARYSEARCHButton
        function BINARYSEARCHButtonPushed(app, event)
            binsearch;
        end

        % Button pushed function: MATRIXCALCULATIONSButton
        function MATRIXCALCULATIONSButtonPushed(app, event)
            matrixm;
        end

        % Button pushed function: COMPARISONButton
        function COMPARISONButtonPushed(app, event)
            comparison;
        end

        % Button pushed function: SORTINGButton
        function SORTINGButtonPushed(app, event)
            Sorting;
        end

        % Button pushed function: FIBONACCISEQUENCEButton
        function FIBONACCISEQUENCEButtonPushed(app, event)
            fibonacci;
        end

        % Button pushed function: GuideButton
        function GuideButtonPushed(app, event)
            winopen("Guide_myapp.pdf")
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            close(app.HKAppUIFigure)
            exit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HKAppUIFigure = uifigure('Visible', 'off');
            app.HKAppUIFigure.Name = 'HÖK App';
            app.HKAppUIFigure.Icon = 'Logohok (1).png';
            app.GridLayout = uigridlayout(app.HKAppUIFigure);
            app.LogInPanel = uipanel(app.GridLayout);
            app.LogInPanel.Title = 'LogIn';
            app.EnterButton = uibutton(app.LogInPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Icon = 'istockphoto-1300637525-170667a.jpg';
            app.EnterButton.Text = 'Enter';
            app.UserNameEditField = uieditfield(app.LogInPanel, 'text');
            app.UserNameEditFieldLabel = uilabel(app.LogInPanel);
            app.UserNameEditFieldLabel.Text = 'User Name:';
            app.Label = uilabel(app.LogInPanel);
            app.Label.Text = '';
            app.Lamp = uilamp(app.LogInPanel);
            app.Label_2 = uilabel(app.LogInPanel);
            app.Label_2.Text = '';
            app.PasswordEditFieldLabel = uilabel(app.LogInPanel);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.LogInPanel, 'text');
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Logohok (1).gif';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '1d5ebd5a-7bef-4aa4-ab48-c893eebc041e.png';
            app.Button.Text = '';
            app.HalimezgeKABAK180403001Label = uilabel(app.GridLayout);
            app.HalimezgeKABAK180403001Label.Text = {'Halime Özge KABAK'; '180403001'};
            app.IKCUEEEYoutubePageButton = uibutton(app.GridLayout, 'push');
            app.IKCUEEEYoutubePageButton.ButtonPushedFcn = createCallbackFcn(app, @IKCUEEEYoutubePageButtonPushed, true);
            app.IKCUEEEYoutubePageButton.Icon = 'youtube-logo-6019878_1280.png';
            app.IKCUEEEYoutubePageButton.Text = {'IKCU EEE  '; 'Youtube Page'};
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Circle-icons-mail.svg.png';
            app.Button_2.Text = '';
            app.BINARYSEARCHButton = uibutton(app.GridLayout, 'push');
            app.BINARYSEARCHButton.ButtonPushedFcn = createCallbackFcn(app, @BINARYSEARCHButtonPushed, true);
            app.BINARYSEARCHButton.Visible = 'off';
            app.BINARYSEARCHButton.Text = 'BINARY SEARCH';
            app.FIBONACCISEQUENCEButton = uibutton(app.GridLayout, 'push');
            app.FIBONACCISEQUENCEButton.ButtonPushedFcn = createCallbackFcn(app, @FIBONACCISEQUENCEButtonPushed, true);
            app.FIBONACCISEQUENCEButton.Visible = 'off';
            app.FIBONACCISEQUENCEButton.Text = 'FIBONACCI SEQUENCE';
            app.SORTINGButton = uibutton(app.GridLayout, 'push');
            app.SORTINGButton.ButtonPushedFcn = createCallbackFcn(app, @SORTINGButtonPushed, true);
            app.SORTINGButton.Visible = 'off';
            app.SORTINGButton.Text = 'SORTING';
            app.COMPARISONButton = uibutton(app.GridLayout, 'push');
            app.COMPARISONButton.ButtonPushedFcn = createCallbackFcn(app, @COMPARISONButtonPushed, true);
            app.COMPARISONButton.Visible = 'off';
            app.COMPARISONButton.Text = 'COMPARISON';
            app.MATRIXCALCULATIONSButton = uibutton(app.GridLayout, 'push');
            app.MATRIXCALCULATIONSButton.ButtonPushedFcn = createCallbackFcn(app, @MATRIXCALCULATIONSButtonPushed, true);
            app.MATRIXCALCULATIONSButton.Visible = 'off';
            app.MATRIXCALCULATIONSButton.Text = 'MATRIX CALCULATIONS';
            app.FINDIthSMALLESTNUMBERButton = uibutton(app.GridLayout, 'push');
            app.FINDIthSMALLESTNUMBERButton.ButtonPushedFcn = createCallbackFcn(app, @FINDIthSMALLESTNUMBERButtonPushed, true);
            app.FINDIthSMALLESTNUMBERButton.Visible = 'off';
            app.FINDIthSMALLESTNUMBERButton.Text = 'FIND Ith SMALLEST NUMBER';
            app.GuideButton = uibutton(app.GridLayout, 'push');
            app.GuideButton.ButtonPushedFcn = createCallbackFcn(app, @GuideButtonPushed, true);
            app.GuideButton.Text = 'Guide';
            app.EXITButton = uibutton(app.GridLayout, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Icon = '95841021-emergency-exit-simple-icon.jpg';
            app.EXITButton.Text = 'EXIT';
            % Show the figure after all components are created
            app.HKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = log

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HKAppUIFigure)
        end
    end
end