classdef matrixm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HKAppUIFigure                   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        EnterFirstArraysRowNumberLabel  matlab.ui.control.Label
        EnterFirstArraysRowNumberSpinner  matlab.ui.control.Spinner
        EnterSecondArraysRowNumberLabel  matlab.ui.control.Label
        EnterSecondArraysRowNumberSpinner  matlab.ui.control.Spinner
        EnterFirstArraysColumnNumberLabel  matlab.ui.control.Label
        EnterFirstArraysColumnNumberSpinner  matlab.ui.control.Spinner
        EnterSecondArraysColumnNumberSpinnerLabel  matlab.ui.control.Label
        EnterSecondArraysColumnNumberSpinner  matlab.ui.control.Spinner
        GenerateMatrixButton            matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        UITable_3                       matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        DivisionButton                  matlab.ui.control.Button
        MultiplicationButton            matlab.ui.control.Button
        SubtractionButton               matlab.ui.control.Button
        SummationButton                 matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        ClearMatricesButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        result
        firstarray
        secondarray
        final
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.result=[];
           app.final=[];
            r=app.EnterFirstArraysRowNumberSpinner.Value;
            c=app.EnterFirstArraysColumnNumberSpinner.Value; 
             r2=app.EnterSecondArraysRowNumberSpinner.Value;
           c2=app.EnterSecondArraysColumnNumberSpinner.Value;
            app.UITable.Data = randi([-100, 100], [r,c]);
             app.UITable_2.Data = randi([-100, 100], [r2,c2]);
              app.UITable_3.Data = randi([-1000, 1000], [r,c2]);
              appdesigner("exit.mlapp")
              appdesigner("log.mlapp")
        end

        % Button pushed function: GenerateMatrixButton
        function GenerateMatrixButtonPushed(app, event)
            r=app.EnterFirstArraysRowNumberSpinner.Value;
            c=app.EnterFirstArraysColumnNumberSpinner.Value; 
          app.firstarray= randi([-100, 100], [r,c]);
             app.UITable.Data=app.firstarray;
           r2=app.EnterSecondArraysRowNumberSpinner.Value;
           c2=app.EnterSecondArraysColumnNumberSpinner.Value;
              app.secondarray=randi([-100, 100], [r2,c2]);
           app.UITable_2.Data =app.secondarray;
           if c~=r2
               app.MultiplicationButton.Enable="off";
               app.DivisionButton.Enable="off";
           elseif c==r2
               app.MultiplicationButton.Enable="on";
               app.DivisionButton.Enable="on";
           end
           if c~=c2 && r~=r2
               app.SummationButton.Enable="off";
               app.SubtractionButton.Enable="off";
           elseif c==c2 && r==r2
               app.SummationButton.Enable="on";
               app.SubtractionButton.Enable="on";
           else
               app.SummationButton.Enable="off";
               app.SubtractionButton.Enable="off";
           end
        end

        % Button pushed function: MultiplicationButton
        function MultiplicationButtonPushed(app, event)
           r=app.EnterFirstArraysRowNumberSpinner.Value;
            c=app.EnterFirstArraysColumnNumberSpinner.Value; 
            
           c2=app.EnterSecondArraysColumnNumberSpinner.Value;
                app.result=zeros(r,c2); 
                for i=1:r 
                   for j=1:c2
                        for k=1:c
                            app.result(i,j)=app.result(i,j)+ (app.firstarray(i,k)*app.secondarray(k,j)); 
                        end   
                   end  
                end


            app.UITable_3.Data =app.result;
           
            app.UITable_3.Data =app.result;
        end

        % Button pushed function: SummationButton
        function SummationButtonPushed(app, event)
           r=app.EnterFirstArraysRowNumberSpinner.Value;
            c=app.EnterFirstArraysColumnNumberSpinner.Value; 
            
                app.final=zeros(r,c); 
                for i=1:r 
                   
                        for k=1:c
                            app.final(i,k)=app.final(i,k)+ (app.firstarray(i,k)+app.secondarray(i,k)); 
                        end   
                        
                end


            app.UITable_3.Data =app.final;
        end

        % Button pushed function: SubtractionButton
        function SubtractionButtonPushed(app, event)
            r=app.EnterFirstArraysRowNumberSpinner.Value;
            c=app.EnterFirstArraysColumnNumberSpinner.Value; 
            
                app.final=zeros(r,c); 
                for i=1:r 
                   
                        for k=1:c
                            app.final(i,k)=app.final(i,k)+ (app.firstarray(i,k)-app.secondarray(i,k)); 
                        end   
                        
                end


            app.UITable_3.Data =app.final;
        end

        % Button pushed function: DivisionButton
        function DivisionButtonPushed(app, event)
              r=app.EnterFirstArraysRowNumberSpinner.Value;
            c=app.EnterFirstArraysColumnNumberSpinner.Value; 
            
           c2=app.EnterSecondArraysColumnNumberSpinner.Value;
                D=zeros(r,c2); 
                for i=1:r 
                   for j=1:c2
                        for k=1:c
                            D(i,j)=D(i,j)+ (app.firstarray(i,k)/app.secondarray(k,j)); 
                        end   
                   end  
                end


            app.UITable_3.Data =D;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            close(app.HKAppUIFigure)
            log;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            close(app.HKAppUIFigure)
            exit;
        end

        % Button pushed function: ClearMatricesButton
        function ClearMatricesButtonPushed(app, event)
            app.UITable.Data = {};
            app.UITable_2.Data = {};
            app.UITable_3.Data={};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HKAppUIFigure = uifigure('Visible', 'off');
            app.HKAppUIFigure.Name = 'HÖK App';
            app.HKAppUIFigure.Icon = 'Logohok (1).png';
            app.GridLayout = uigridlayout(app.HKAppUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'; 'Column 16'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.EnterFirstArraysRowNumberLabel = uilabel(app.GridLayout);
            app.EnterFirstArraysRowNumberLabel.Text = {'Enter First '; 'Arrays'; 'Row Number:'; ' '};
            app.EnterFirstArraysRowNumberSpinner = uispinner(app.GridLayout);
            app.EnterFirstArraysRowNumberSpinner.Limits = [0 Inf];
            app.EnterSecondArraysRowNumberLabel = uilabel(app.GridLayout);
            app.EnterSecondArraysRowNumberLabel.Text = {'Enter Second '; 'Arrays Row'; 'Number:'};
            app.EnterSecondArraysRowNumberSpinner = uispinner(app.GridLayout);
            app.EnterSecondArraysRowNumberSpinner.Limits = [0 Inf];
            app.EnterFirstArraysColumnNumberLabel = uilabel(app.GridLayout);
            app.EnterFirstArraysColumnNumberLabel.Text = {'Enter First'; 'Arrays'; 'Column Number:'};
            app.EnterFirstArraysColumnNumberSpinner = uispinner(app.GridLayout);
            app.EnterFirstArraysColumnNumberSpinner.Limits = [0 Inf];
            app.EnterSecondArraysColumnNumberSpinnerLabel = uilabel(app.GridLayout);
            app.EnterSecondArraysColumnNumberSpinnerLabel.Text = {'Enter Second'; 'Arrays Column'; 'Number:'};
            app.EnterSecondArraysColumnNumberSpinner = uispinner(app.GridLayout);
            app.EnterSecondArraysColumnNumberSpinner.Limits = [0 Inf];
            app.GenerateMatrixButton = uibutton(app.GridLayout, 'push');
            app.GenerateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonPushed, true);
            app.GenerateMatrixButton.Text = 'Generate Matrix';
            app.UITable_2 = uitable(app.GridLayout);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'; 'Column 16'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable_2.RowName = {};
            app.UITable_3 = uitable(app.GridLayout);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'; 'Column 16'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable_3.RowName = {};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Logohok (1).png';
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = 'mathsymbolequalsrgb_p.png';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '';
            app.Panel = uipanel(app.GridLayout);
            app.DivisionButton = uibutton(app.Panel, 'push');
            app.DivisionButton.ButtonPushedFcn = createCallbackFcn(app, @DivisionButtonPushed, true);
            app.DivisionButton.Text = 'Division';
            app.MultiplicationButton = uibutton(app.Panel, 'push');
            app.MultiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicationButtonPushed, true);
            app.MultiplicationButton.Text = 'Multiplication';
            app.SubtractionButton = uibutton(app.Panel, 'push');
            app.SubtractionButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractionButtonPushed, true);
            app.SubtractionButton.Text = 'Subtraction';
            app.SummationButton = uibutton(app.Panel, 'push');
            app.SummationButton.ButtonPushedFcn = createCallbackFcn(app, @SummationButtonPushed, true);
            app.SummationButton.Text = 'Summation';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '95841021-emergency-exit-simple-icon.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'indir.png';
            app.Button_2.Text = '';
            app.ClearMatricesButton = uibutton(app.GridLayout, 'push');
            app.ClearMatricesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMatricesButtonPushed, true);
            app.ClearMatricesButton.Text = 'Clear Matrices';
            % Show the figure after all components are created
            app.HKAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matrixm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HKAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HKAppUIFigure)
        end
    end
end